classdef SimplexAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlgoritmoSimplexUIFigure    matlab.ui.Figure
        cEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        bEditFieldLabel             matlab.ui.control.Label
        bEditField                  matlab.ui.control.EditField
        ATextAreaLabel              matlab.ui.control.Label
        ATextArea                   matlab.ui.control.TextArea
        MensajeLabel                matlab.ui.control.Label
        UsaralgoritmoSimplexButton  matlab.ui.control.Button
        SolucinEditFieldLabel       matlab.ui.control.Label
        SolucinEditField            matlab.ui.control.EditField
        SimplexOutput               matlab.ui.control.Label
    end

    
    properties (Access = private)
        A    % Matriz A
        b    % Vector de restricciones
        c    % Coeficientes de vector objetivo
        x    % Resultado
    end
    
    methods (Access = private)
        
function x = Simplex(~, A, b, c, msj)
% El método simplex resuelve problemas de programación
% lineal en su primera forma primal:
%
% maximizar c' * x,
% sujeto a Ax <= b, x >= 0,
% con b >= 0.
%
% *INPUT*
% A:    Matriz de m*n
% b:    Vector columna de m*1
% c:    Vector columna de n*1
%
% *OUTPUT*
% x:    Vector columna de n*1

% Valor pequeño para epsilon
epsilon = 10e-6;

% Determinar forma del sistema
[m,k] = size(A);
n = m + k;

% Pasar problema a segunda forma primal
% maximizar c' * x,
% sujeto a Ax <= b, x >= 0
% 
c = [c; repelem(0, m, 1)];
A = [A, eye(m)];

% Índices de la base considerada. La primera 
% base está formada por las últimas m columnas 
% de la matriz A en su segunda forma primal
index = (k+1):n;

% Loop infinito
while (1)
    % Creamos matriz B con la base
    B = A(:,index);
    
    % Resolvemos Bx = b
    x = B \ b;
    
    % Si no todas las entradas de x > 0
    % entonces el algoritmo falló
    if (~all(x > 0))
        msj.Text = "El algoritmo falló";
        fprintf("El algoritmo falló");
        x = repelem(inf, k)';
        break
    end
    
    % Creamos vector con los coeficientes de la 
    % función objetivo correspondientes a la base 
    e = c(index);
    
    % Resolvemos B'y = e
    y = B' \ e;
    
    % Hallamos índice s que no esté en la base
    % que minimiza c(s)' - y' * A(:,s)
    s = setdiff(1:n, index);
    v1 = c' - y' * A;
    maxVal = max(v1(s));
    s = find(v1 == maxVal);
    
    % Si el valor máximo de c(s) - y' * A(,:)
    % es menor que epsilon, hemos hallado la solución
    if (maxVal < epsilon)
        msj.Text = "Solución hallada";
        v = repelem(0, n)';
        v(index) = x;
        x = v(1:k);
        break
    end
    
    % Resolvemos Bz = A(:,s)
    z = B \ A(:,s);
    
    % Si todo z <= epsilon, salimos porque la función 
    % objetivo no está acotada en la región factible
    if (all(z <= epsilon))
        msj.Text = "La función objetivo no está acotada en la región factible.";
        fprintf("La función objetivo no está acotada en la región factible.");
        x = repelem(inf, k)';
        break
    end
    
    % Hallamos índice r que minimiza x/z, donde z > 0
    positiveZ = z > 0;
    v2 = x ./ z;
    minZ = min(v2(positiveZ));
    r = find(v2 == minZ);
    
    % Reemplazamos elemento r de la base por s
    index(r) = s;
    
end

end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            
            app.c = str2num(value)';
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            
            app.b = str2num(value)';
        end

        % Value changed function: ATextArea
        function ATextAreaValueChanged(app, event)
            value = app.ATextArea.Value;
            
            % Matriz resultante
            app.A = [];
            
            % Agrega cada fila a la matriz en orden
            [n,~] = size(value);
            
            for i=1:n
                app.A = [app.A; str2num(cell2mat(value(i)))];
            end
            
        end

        % Button pushed function: UsaralgoritmoSimplexButton
        function UsaralgoritmoSimplexButtonPushed(app, event)
            
            % Mostrar resultado
            app.x = app.Simplex(app.A, app.b, app.c, app.SimplexOutput);
            display(app.x)
            app.SolucinEditField.Value = num2str(app.x');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlgoritmoSimplexUIFigure = uifigure('Visible', 'off');
            app.AlgoritmoSimplexUIFigure.Name = 'Algoritmo Simplex';
            app.cEditFieldLabel = uilabel(app.AlgoritmoSimplexUIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.AlgoritmoSimplexUIFigure, 'text');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.Label = uilabel(app.AlgoritmoSimplexUIFigure);
            app.Label.Text = {'El algoritmo Simplex recibe un problema de '; 'programación lineal en su primera forma primal. '; 'Esto es:'; ''; 'Maximizar c'' * x'; 'Sujeto a: '; 'Ax <= b, x >= 0,'; 'con b >= 0.'; ''; 'El método recibe:'; 'c:    Un arreglo separado por comas de n valores'; 'b:    Un arreglo separado por comas de m valores'; 'A:   Una matriz separada por comas de m*n valores'};
            app.bEditFieldLabel = uilabel(app.AlgoritmoSimplexUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.AlgoritmoSimplexUIFigure, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.ATextAreaLabel = uilabel(app.AlgoritmoSimplexUIFigure);
            app.ATextAreaLabel.Text = 'A';
            app.ATextArea = uitextarea(app.AlgoritmoSimplexUIFigure);
            app.ATextArea.ValueChangedFcn = createCallbackFcn(app, @ATextAreaValueChanged, true);
            app.MensajeLabel = uilabel(app.AlgoritmoSimplexUIFigure);
            app.MensajeLabel.Text = 'Mensaje:';
            app.UsaralgoritmoSimplexButton = uibutton(app.AlgoritmoSimplexUIFigure, 'push');
            app.UsaralgoritmoSimplexButton.ButtonPushedFcn = createCallbackFcn(app, @UsaralgoritmoSimplexButtonPushed, true);
            app.UsaralgoritmoSimplexButton.Text = 'Usar algoritmo Simplex';
            app.SolucinEditFieldLabel = uilabel(app.AlgoritmoSimplexUIFigure);
            app.SolucinEditFieldLabel.Text = 'Solución';
            app.SolucinEditField = uieditfield(app.AlgoritmoSimplexUIFigure, 'text');
            app.SimplexOutput = uilabel(app.AlgoritmoSimplexUIFigure);
            app.SimplexOutput.Text = '';
            % Show the figure after all components are created
            app.AlgoritmoSimplexUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimplexAppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlgoritmoSimplexUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlgoritmoSimplexUIFigure)
        end
    end
end